classdef ct_filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CTimagefiltersUIFigure  matlab.ui.Figure
        SharpenCheckBox         matlab.ui.control.CheckBox
        ADCheckBox              matlab.ui.control.CheckBox
        ADEDiffusionCheckBox    matlab.ui.control.CheckBox
        LoadImageButton         matlab.ui.control.Button
        SelectFiltersLabel      matlab.ui.control.Label
        UIAxesADE               matlab.ui.control.UIAxes
        UIAxesSharpen           matlab.ui.control.UIAxes
        UIAxesAD                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            adeCBvalue = get(app.ADEDiffusionCheckBox, 'Value');
            adCBvalue = get(app.ADCheckBox, 'Value');
            shCBvalue = get(app.SharpenCheckBox, 'Value');
            try
                [filename,filepath] = uigetfile({'*.png'}, 'Select File to Open');
                 fullname = [filepath, filename];
                 ImageFile = imread(fullname);
                 ImageFile = imresize(ImageFile, 2);
                 axis(app.UIAxes,'off');
                 imshow(ImageFile, 'Parent', app.UIAxes);
                 app.UIAxes.Visible = 'on';
                 if adCBvalue
                     axis(app.UIAxesAD,'off');
                     ad = imdiffusefilt(ImageFile);
                     imshow(ad, 'Parent', app.UIAxesAD);
                     app.UIAxes.Visible = 'on';
                 end
                 if adeCBvalue
                     [gradThresh,numIter] = imdiffuseest(ImageFile,'ConductionMethod','quadratic');
                      adEstimated = imdiffusefilt(ImageFile,'ConductionMethod','quadratic', ...
                         'GradientThreshold',gradThresh,'NumberOfIterations',numIter);
                     axis(app.UIAxesADE,'off');
                     imshow(adEstimated, 'Parent', app.UIAxesADE);
                     app.UIAxesADE.Visible = 'on';
                 end
                 if shCBvalue
                     axis(app.UIAxesSharpen,'off');
                     sharpen = imsharpen(ImageFile);
                     imshow(sharpen, 'Parent', app.UIAxesSharpen);
                     app.UIAxesSharpen.Visible = 'on';
                 end
            catch E
                disp(E)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CTimagefiltersUIFigure = uifigure('Visible', 'off');
            app.CTimagefiltersUIFigure.Name = 'CT image filters';
            app.SharpenCheckBox = uicheckbox(app.CTimagefiltersUIFigure);
            app.SharpenCheckBox.Text = {'Sharpen'; ''};
            app.ADCheckBox = uicheckbox(app.CTimagefiltersUIFigure);
            app.ADCheckBox.Text = 'Anisotropic Diffusion';
            app.ADEDiffusionCheckBox = uicheckbox(app.CTimagefiltersUIFigure);
            app.ADEDiffusionCheckBox.Text = 'Anisotropic Diffusion with estimated parameters';
            app.LoadImageButton = uibutton(app.CTimagefiltersUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = {'Load Image'; ''};
            app.SelectFiltersLabel = uilabel(app.CTimagefiltersUIFigure);
            app.SelectFiltersLabel.Text = 'Select Filters';
            app.UIAxesADE = uiaxes(app.CTimagefiltersUIFigure);
            title(app.UIAxesADE, 'Anisotropic Diffusion Estimated')
            app.UIAxesADE.XTick = [];
            app.UIAxesADE.YTick = [];
            app.UIAxesADE.Visible = 'off';
            app.UIAxesSharpen = uiaxes(app.CTimagefiltersUIFigure);
            title(app.UIAxesSharpen, 'Sharpened Image')
            app.UIAxesSharpen.XTick = [];
            app.UIAxesSharpen.YTick = [];
            app.UIAxesSharpen.Visible = 'off';
            app.UIAxesAD = uiaxes(app.CTimagefiltersUIFigure);
            title(app.UIAxesAD, 'Anisotropic Diffusion')
            app.UIAxesAD.XTick = [];
            app.UIAxesAD.YTick = [];
            app.UIAxesAD.Visible = 'off';
            app.UIAxes = uiaxes(app.CTimagefiltersUIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.CTimagefiltersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ct_filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CTimagefiltersUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CTimagefiltersUIFigure)
        end
    end
end